classdef toolbox_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure                   % UI Figure
        LabelNumericEditField  matlab.ui.control.Label            % Time st...
        deltaT                 matlab.ui.control.NumericEditField % [0 1.00...
        LabelNumericEditField2 matlab.ui.control.Label            % Impact ...
        delta_dx               matlab.ui.control.NumericEditField % [-1 -1....
        Label                  matlab.ui.control.Label            % Desired...
        F_d                    matlab.ui.control.NumericEditField % [0 10]
        Label2                 matlab.ui.control.Label            % Final S...
        Tfinal                 matlab.ui.control.NumericEditField % [0 1000]
        Animation              matlab.ui.control.CheckBox         % Animation
        LabelSlider            matlab.ui.control.Label            % X-axis ...
        Limitsx                matlab.ui.control.Slider           % [1 10]
        Label3                 matlab.ui.control.Label            % Y-axis ...
        Limitsy                matlab.ui.control.Slider           % [1 10]
        On_surface             matlab.ui.control.Button           % Initial...
        Off_surface            matlab.ui.control.Button           % Initial...
        Label4                 matlab.ui.control.Label            % Example...
        LabelSlider2           matlab.ui.control.Label            % Rho
        Rho                    matlab.ui.control.Slider           % [5.0000...
        UIAxes                 matlab.ui.control.UIAxes          
        Button                 matlab.ui.control.Button           % Start t...
    end

    
    properties (Access = public)
        Play_with_rho =0;
        Option;
        N_x;
        Poly;
        X_target;
        X_free;
        X_initial;
        X_L;
        X_C;
        A;
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('CoDS'))
            setup_CoDs;
            app.Play_with_rho=0;
        end

        % On_surface button pushed function
        function On_surfaceButtonPushed(app)
            app.Play_with_rho=0;
            app.Option.Figure = figure;
            app.Option.Figure.Visible = 'off';
            app.Option.limits= [-app.Limitsx.Value app.Limitsx.Value -app.Limitsy.Value app.Limitsy.Value];
            app.Option.check=0;
            app.Option.rho=app.Rho.Value;
            app.Option.Deltat=app.deltaT.Value;
            app.Option.F_d=app.F_d.Value;
            app.Option.delta_dx=app.delta_dx.Value;
            app.Option.Tfinal=app.Tfinal.Value;
            app.Option.animation=app.Animation.Value;
            app.Option.Onsurface=1;
            disp('Draw the contact surface')
            app.Option.axes=app.UIAxes;
            delete(findobj(app.Option.axes, 'type', 'line'));
            [app.N_x,app.Poly,app.X_target,app.X_free,app.Option.check,fig]=Construct_the_surface_for_on_surface(app.Option);
            if app.Option.check==0
                error('Program exit')
            end
            disp('Draw some motions, make sure that it ends up at the target point and it goes through the contact surface !')
            [app.A,app.X_initial,app.Option.check]=Construct_the_dynamcial_system(app.Poly,app.X_target,app.X_free,app.Option,fig);
            if app.Option.check==0
                error('Program exit')
            end
            [app.X_C,app.X_L]=Select_the_contact_point_on_surface(app.Poly,app.X_target,app.X_initial,app.Option);
            
            limits=app.Option.limits;
            app.UIAxes.XLim =[-app.Limitsx.Value app.Limitsx.Value];
            app.UIAxes.YLim =[-app.Limitsy.Value app.Limitsy.Value];
            close all
            pause(0.5)
            X = linspace(limits(1,1),limits(1,2),100);
            Y = app.Poly(1)*X+app.Poly(2);
            h3=plot(app.UIAxes,X,Y,'LineWidth',4,'LineStyle','--','Color',[0 0 0]);
            hold(app.UIAxes)
            h2=plot(app.UIAxes,app.X_target(1,1),app.X_target(2,1),'MarkerFaceColor',[0 0 1],'MarkerEdgeColor','none','MarkerSize',30,'Marker','pentagram','LineWidth',5,'LineStyle','none');
            h1=plot(app.UIAxes,app.X_initial(1,:),app.X_initial(2,:),'MarkerFaceColor',[0.466666668653488 0.674509823322296 0.18823529779911],'MarkerEdgeColor','none','MarkerSize',30,'Marker','hexagram','LineWidth',5,'LineStyle','none');
            h4=plot(app.UIAxes,app.X_C(1,1),app.X_C(2,1),'MarkerFaceColor',[0 0.447058826684952 0.74117648601532],'MarkerSize',30,'Marker','^','LineStyle','none');
            app.Play_with_rho=1;
        end

        % Off_surface button pushed function
        function Off_surfaceButtonPushed(app)
            app.Play_with_rho=0;
            app.Option.limits= [-app.Limitsx.Value app.Limitsx.Value -app.Limitsy.Value app.Limitsy.Value];
            app.Option.check=0;
            app.Option.rho=app.Rho.Value;
            app.Option.Deltat=app.deltaT.Value;
            app.Option.F_d=app.F_d.Value;
            app.Option.delta_dx=app.delta_dx.Value;
            app.Option.Tfinal=app.Tfinal.Value;
            app.Option.animation=app.Animation.Value;
            app.Option.Onsurface=0;
            app.Option.Figure = figure;
            app.Option.Figure.Visible = 'off';
            app.Option.axes=app.UIAxes;
            disp('Draw the contact surface')
            delete(findobj(app.Option.axes, 'type', 'line'));
            [app.N_x,app.Poly,app.X_target,app.Option.check,fig]=Construct_the_surface(app.Option);
            if app.Option.check==0
                error('Program exit')
            end
            %% Constructing the dynamcail system
            disp('Draw some motions, make sure that it ends up at the target point and it goes through the contact surface !')
            [app.A,app.X_initial,app.Option.check]=Construct_the_dynamcial_system(app.Poly,app.X_target,app.X_target,app.Option,fig);
            if app.Option.check==0
                error('Program exit')
            end
            [app.X_C,app.X_L]=Select_the_contact_point(app.Poly,app.X_target,app.X_initial,app.Option);
            limits=app.Option.limits;
            app.UIAxes.XLim =[-app.Limitsx.Value app.Limitsx.Value];
            app.UIAxes.YLim =[-app.Limitsy.Value app.Limitsy.Value];
            close all
            pause(0.5)
            X = linspace(limits(1,1),limits(1,2),100);
            Y = app.Poly(1)*X+app.Poly(2);
            h3=plot(app.UIAxes,X,Y,'LineWidth',4,'LineStyle','--','Color',[0 0 0]);
            hold(app.UIAxes)
            h2=plot(app.UIAxes,app.X_target(1,1),app.X_target(2,1),'MarkerFaceColor',[0 0 1],'MarkerEdgeColor','none','MarkerSize',30,'Marker','pentagram','LineWidth',5,'LineStyle','none');
            h1=plot(app.UIAxes,app.X_initial(1,:),app.X_initial(2,:),'MarkerFaceColor',[0.466666668653488 0.674509823322296 0.18823529779911],'MarkerEdgeColor','none','MarkerSize',30,'Marker','hexagram','LineWidth',5,'LineStyle','none');
            h4=plot(app.UIAxes,app.X_C(1,1),app.X_C(2,1),'MarkerFaceColor',[0 0.447058826684952 0.74117648601532],'MarkerSize',30,'Marker','^','LineStyle','none');
            h5=plot(app.UIAxes,app.X_L(1,1),app.X_L(2,1),'MarkerFaceColor',[1 0 0],'MarkerSize',30,'Marker','v','LineStyle','none');
            app.X_free=app.X_target;
            app.Play_with_rho=1;
        end

        % Limitsx value changing function
        function LimitsxValueChanging(app, event)
            changingValue = event.Value;
            app.UIAxes.XLim =[-changingValue changingValue];
        end

        % Limitsy value changing function
        function LimitsyValueChanging(app, event)
            changingValue = event.Value;
            app.UIAxes.YLim =[-changingValue changingValue];
        end

        % Rho value changed function
        function RhoValueChanged(app)
            app.Option.rho= app.Rho.Value;
            if app.Play_with_rho
                X1=[ app.Option.limits(1):0.05: app.Option.limits(2)];
                X2=[ app.Option.limits(3):0.05: app.Option.limits(4)]';
                
                X1=repmat(X1,size(X2,1),1);
                X2=repmat(X2,1,size(X1,2));
                Walla=zeros(size(X2));
                Wall_Base=app.N_x'*app.X_C;
                
                Handle_sign=sign(app.N_x'*app.X_free);
                for ii=1:size(X2,1),
                    for jj=1:size(X2,2)
                        XX=[X1(ii,jj);X2(ii,jj)];
                        Walla(ii,jj)=Handle_sign*(app.N_x'*XX-Wall_Base)+...
                            (app.Option.rho-(app.X_L-app.X_C)'*(app.X_L-XX))*exp(-2*(app.X_L-XX)'*(app.X_L-XX));
                        if  app.Option.rho<(Walla(ii,jj))
                            Walla(ii,jj)=app.Option.rho;
                        end
                        if Walla(ii,jj)<-2
                            Walla(ii,jj)=-2;
                        end
                    end
                end
                clim=[-2 app.Option.rho];
                contourf(X1,X2,Walla);
                colormap(hot);
                hold on
                X = linspace( app.Option.limits(1), app.Option.limits(2),100);
                Y = app.Poly(1)*X+app.Poly(2);
                h3=plot(X,Y,'LineWidth',4,'LineStyle','--','Color',[0 0 0]);
                hold on
                h2=plot(app.X_target(1,1),app.X_target(2,1),'MarkerFaceColor',[0 0 1],'MarkerEdgeColor','none','MarkerSize',30,'Marker','pentagram','LineWidth',5,'LineStyle','none');
                h1=plot(app.X_initial(1,:),app.X_initial(2,:),'MarkerFaceColor',[0.466666668653488 0.674509823322296 0.18823529779911],'MarkerEdgeColor','none','MarkerSize',30,'Marker','hexagram','LineWidth',5,'LineStyle','none');
                h4=plot(app.UIAxes,app.X_C(1,1),app.X_C(2,1),'MarkerFaceColor',[0 0.447058826684952 0.74117648601532],'MarkerSize',30,'Marker','^','LineStyle','none');
                ylim([app.Option.limits(3) app.Option.limits(4)]);
                xlim([app.Option.limits(1) app.Option.limits(2)]);
            end
        end

        % Button button pushed function
        function Start_simulationPushed(app)
            %% Simulate the unmodulated system
            if app.Play_with_rho
                [DDX_modulated,DX_modulated,X_modulated,F_modulated,Time_modulated]=  simulate_modulated_system(app.A,app.N_x,app.Poly,app.X_initial,app.X_target,app.X_free,app.X_C,app.X_L,app.Option);
                plot_the_simualtions(DDX_modulated,DX_modulated,X_modulated,F_modulated,Time_modulated,app.Poly,app.X_initial,app.X_target,app.X_free,app.X_C,app.X_L,app.N_x,app.Option);
            else
                disp('The simulator must be initialized first!')
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.LabelNumericEditField = uilabel(app.UIFigure);
            app.LabelNumericEditField.Text = 'Time step [s]';
            app.deltaT = uieditfield(app.UIFigure, 'numeric');
            app.deltaT.Limits = [0 0.01];
            app.deltaT.Value = 0.001;
            app.LabelNumericEditField2 = uilabel(app.UIFigure);
            app.LabelNumericEditField2.Text = 'Impact velocity [m/s]';
            app.delta_dx = uieditfield(app.UIFigure, 'numeric');
            app.delta_dx.Limits = [-1 -1e-05];
            app.delta_dx.Value = -0.5;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Desired contact force [N]';
            app.F_d = uieditfield(app.UIFigure, 'numeric');
            app.F_d.Limits = [0 10];
            app.F_d.Value = 6;
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Final Simulation time [s]';
            app.Tfinal = uieditfield(app.UIFigure, 'numeric');
            app.Tfinal.Limits = [0 1000];
            app.Tfinal.Value = 100;
            app.Animation = uicheckbox(app.UIFigure);
            app.Animation.Text = 'Animation';
            app.Animation.Value = true;
            app.LabelSlider = uilabel(app.UIFigure);
            app.LabelSlider.Text = 'X-axis Limits';
            app.Limitsx = uislider(app.UIFigure);
            app.Limitsx.Limits = [1 10];
            app.Limitsx.ValueChangingFcn = createCallbackFcn(app, @LimitsxValueChanging, true);
            app.Limitsx.Value = 5;
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'Y-axis Limits';
            app.Limitsy = uislider(app.UIFigure);
            app.Limitsy.Limits = [1 10];
            app.Limitsy.ValueChangingFcn = createCallbackFcn(app, @LimitsyValueChanging, true);
            app.Limitsy.Value = 5;
            app.On_surface = uibutton(app.UIFigure, 'push');
            app.On_surface.ButtonPushedFcn = createCallbackFcn(app, @On_surfaceButtonPushed);
            app.On_surface.Text = {'Initialize the simulator'; '(Target on the surface)'};
            app.Off_surface = uibutton(app.UIFigure, 'push');
            app.Off_surface.ButtonPushedFcn = createCallbackFcn(app, @Off_surfaceButtonPushed);
            app.Off_surface.Text = {'Initialize the simulator'; '(Target off the surface)'};
            app.Label4 = uilabel(app.UIFigure);
            app.Label4.Text = {'Examples of using the toolbox are available'; ' in the folder named "Video" .'};
            app.LabelSlider2 = uilabel(app.UIFigure);
            app.LabelSlider2.Text = 'Rho';
            app.Rho = uislider(app.UIFigure);
            app.Rho.Limits = [0.5 10];
            app.Rho.ValueChangedFcn = createCallbackFcn(app, @RhoValueChanged);
            app.Rho.Value = 2;
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X [m]');
            ylabel(app.UIAxes, 'Y  [m]');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @Start_simulationPushed);
            app.Button.Text = 'Start the simulation';
        end
    end

    methods (Access = public)

        % Construct app
        function app = toolbox_GUI()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end